classdef RadarTrack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_2                 matlab.ui.container.Panel
        EditField_3             matlab.ui.control.NumericEditField
        Label_13                matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        Label_14                matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        Label_12                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        CheckBox_CI             matlab.ui.control.CheckBox
        TabGroup2               matlab.ui.container.TabGroup
        Tab_4                   matlab.ui.container.Tab
        ButtonGroup             matlab.ui.container.ButtonGroup
        EKFButton               matlab.ui.control.RadioButton
        KFButton                matlab.ui.control.RadioButton
        Button_stateok_radar1f  matlab.ui.control.Button
        EditField_Rpol1f_theta  matlab.ui.control.NumericEditField
        Label_20                matlab.ui.control.Label
        EditField_Rpol1f_rho    matlab.ui.control.NumericEditField
        thetaLabel_6            matlab.ui.control.Label
        rhomLabel_6             matlab.ui.control.Label
        EditField_Qf_vy_1       matlab.ui.control.NumericEditField
        EditField_Qf_vx_1       matlab.ui.control.NumericEditField
        Label_19                matlab.ui.control.Label
        xmsLabel_5              matlab.ui.control.Label
        ymsLabel_6              matlab.ui.control.Label
        Tab_5                   matlab.ui.container.Tab
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        EKFButton_2             matlab.ui.control.RadioButton
        KFButton_2              matlab.ui.control.RadioButton
        Button_stateok_radar2f  matlab.ui.control.Button
        EditField_Rpol2f_theta  matlab.ui.control.NumericEditField
        Label_18                matlab.ui.control.Label
        EditField_Rpol2f_rho    matlab.ui.control.NumericEditField
        thetaLabel_5            matlab.ui.control.Label
        rhomLabel_5             matlab.ui.control.Label
        EditField_Qf_vy_2       matlab.ui.control.NumericEditField
        EditField_Qf_vx_2       matlab.ui.control.NumericEditField
        Label_17                matlab.ui.control.Label
        xmsLabel_4              matlab.ui.control.Label
        ymsLabel_5              matlab.ui.control.Label
        Tab_6                   matlab.ui.container.Tab
        ButtonGroup_3           matlab.ui.container.ButtonGroup
        EKFButton_3             matlab.ui.control.RadioButton
        KFButton_3              matlab.ui.control.RadioButton
        Button_stateok_radar3f  matlab.ui.control.Button
        EditField_Rpol3f_theta  matlab.ui.control.NumericEditField
        Label_16                matlab.ui.control.Label
        EditField_Rpol3f_rho    matlab.ui.control.NumericEditField
        thetaLabel_4            matlab.ui.control.Label
        rhomLabel_4             matlab.ui.control.Label
        EditField_Qf_vy_3       matlab.ui.control.NumericEditField
        EditField_Qf_vx_3       matlab.ui.control.NumericEditField
        Label_15                matlab.ui.control.Label
        xmsLabel_3              matlab.ui.control.Label
        ymsLabel_4              matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Button_stateok          matlab.ui.control.Button
        EditField_Q_vy          matlab.ui.control.NumericEditField
        EditField_Q_vx          matlab.ui.control.NumericEditField
        EditField_X_vy          matlab.ui.control.NumericEditField
        EditField_X_vx          matlab.ui.control.NumericEditField
        EditField_X_y           matlab.ui.control.NumericEditField
        EditField_X_x           matlab.ui.control.NumericEditField
        EditField_T             matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        EditField_k             matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        ymsLabel_2              matlab.ui.control.Label
        xmsLabel_2              matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        ymsLabel_3              matlab.ui.control.Label
        ymLabel                 matlab.ui.control.Label
        xmsLabel                matlab.ui.control.Label
        xmLabel                 matlab.ui.control.Label
        SLabel                  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab_1                   matlab.ui.container.Tab
        Button_stateok_radar1   matlab.ui.control.Button
        Label_8                 matlab.ui.control.Label
        EditField_Rpol1_rho     matlab.ui.control.NumericEditField
        EditField_Rpol1_theta   matlab.ui.control.NumericEditField
        thetaradianLabel        matlab.ui.control.Label
        rhomLabel               matlab.ui.control.Label
        ymLabel_2               matlab.ui.control.Label
        EditField_origin1_y     matlab.ui.control.NumericEditField
        xmLabel_2               matlab.ui.control.Label
        EditField_origin1_x     matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        Tab_2                   matlab.ui.container.Tab
        Button_stateok_radar2   matlab.ui.control.Button
        Label_9                 matlab.ui.control.Label
        EditField_Rpol2_rho     matlab.ui.control.NumericEditField
        EditField_Rpol2_theta   matlab.ui.control.NumericEditField
        thetaLabel_2            matlab.ui.control.Label
        rhomLabel_2             matlab.ui.control.Label
        ymLabel_3               matlab.ui.control.Label
        EditField_origin2_y     matlab.ui.control.NumericEditField
        xmLabel_3               matlab.ui.control.Label
        EditField_origin2_x     matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        Tab_3                   matlab.ui.container.Tab
        Button_stateok_radar3   matlab.ui.control.Button
        Label_10                matlab.ui.control.Label
        EditField_Rpol3_rho     matlab.ui.control.NumericEditField
        EditField_Rpol3_theta   matlab.ui.control.NumericEditField
        thetaLabel_3            matlab.ui.control.Label
        rhomLabel_3             matlab.ui.control.Label
        ymLabel_4               matlab.ui.control.Label
        EditField_origin3_y     matlab.ui.control.NumericEditField
        xmLabel_4               matlab.ui.control.Label
        EditField_origin3_x     matlab.ui.control.NumericEditField
        Label_7                 matlab.ui.control.Label
        PCRLBCheckBox           matlab.ui.control.CheckBox
        MonteCarloCheckBox      matlab.ui.control.CheckBox
        CheckBox_Xhat3          matlab.ui.control.CheckBox
        CheckBox_Xhat2          matlab.ui.control.CheckBox
        CheckBox_Xhat1          matlab.ui.control.CheckBox
        CheckBox_Z3             matlab.ui.control.CheckBox
        CheckBox_Z2             matlab.ui.control.CheckBox
        CheckBox_Z1             matlab.ui.control.CheckBox
        CheckBox_X              matlab.ui.control.CheckBox
        UIAxes1                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_stateok
        function Button_stateokPushed(app, event)
            if(app.EditField_Q_vx.Value == 0 || app.EditField_Q_vy.Value == 0)
                msg = '运动噪声协方差不能为0';
                error(msg);
            end
            Target.X0 = [app.EditField_X_x.Value 
                app.EditField_X_vx.Value 
                app.EditField_X_y.Value 
                app.EditField_X_vy.Value ];
            Target.q = [app.EditField_Q_vx.Value^2 0;0 app.EditField_Q_vy.Value ^2];
            Target.nIter = app.EditField_k.Value;
            Target.dt = app.EditField_T.Value;

            Target = constantVelocity(Target);
            app.Button_stateok.UserData = struct('X1',Target.X,'Q1',Target.Q);
            app.Button_stateok.UserData.plot = plot(app.UIAxes1,Target.X(1,:),Target.X(3,:),'k','LineWidth',2);
            app.CheckBox_X.Value = true;
            hold(app.UIAxes1);
        end

        % Button pushed function: Button_stateok_radar1
        function Button_stateok_radar1Pushed(app, event)
            Rpol1 = [app.EditField_Rpol1_theta.Value^2 0;0 app.EditField_Rpol1_rho.Value^2];
            origin1 = [app.EditField_origin1_x.Value app.EditField_origin1_y.Value]';
            [Zpol1_1,Zcart1_1] = polarMeasurement(app.Button_stateok.UserData.X1,Rpol1,origin1);
            app.Button_stateok_radar1.UserData = struct('Zpol1_1',Zpol1_1,'Zcart1_1',Zcart1_1,'origin1',origin1);
            app.Button_stateok_radar1.UserData.scatter = scatter(app.UIAxes1,origin1(1,1),origin1(2,1),'r','LineWidth',2,'DisplayName','雷达1 量测点');
            app.Button_stateok_radar1.UserData.plot = plot(app.UIAxes1,Zcart1_1(1,:),Zcart1_1(2,:),'r','LineWidth',2);
            app.CheckBox_Z1.Value = true;
        end

        % Button pushed function: Button_stateok_radar2
        function Button_stateok_radar2Pushed(app, event)
            Rpol2 = [app.EditField_Rpol2_theta.Value^2 0;0 app.EditField_Rpol2_rho.Value^2];
            origin2 = [app.EditField_origin2_x.Value app.EditField_origin2_y.Value]';
            [Zpol1_2,Zcart1_2] = polarMeasurement(app.Button_stateok.UserData.X1,Rpol2,origin2);
            app.Button_stateok_radar2.UserData = struct('Zpol1_2',Zpol1_2,'Zcart1_2',Zcart1_2,'origin2',origin2);
            app.Button_stateok_radar2.UserData.scatter = scatter(app.UIAxes1,origin2(1,1),origin2(2,1),'b','LineWidth',2,'DisplayName','雷达2 量测点');
            app.Button_stateok_radar2.UserData.plot = plot(app.UIAxes1,Zcart1_2(1,:),Zcart1_2(2,:),'b','LineWidth',2);
            app.CheckBox_Z2.Value = true;
        end

        % Button pushed function: Button_stateok_radar3
        function Button_stateok_radar3Pushed(app, event)
            Rpol3 = [app.EditField_Rpol3_theta.Value^2 0;0 app.EditField_Rpol3_rho.Value^2];
            origin3 = [app.EditField_origin3_x.Value app.EditField_origin3_y.Value]';
            [Zpol1_3,Zcart1_3] = polarMeasurement(app.Button_stateok.UserData.X1,Rpol3,origin3);
            app.Button_stateok_radar3.UserData = struct('Zpol1_3',Zpol1_3,'Zcart1_3',Zcart1_3,'origin3',origin3);
            app.Button_stateok_radar3.UserData.scatter = scatter(app.UIAxes1,origin3(1,1),origin3(2,1),'g','LineWidth',2,'DisplayName','雷达3 量测点');
            app.Button_stateok_radar3.UserData.plot = plot(app.UIAxes1,Zcart1_3(1,:),Zcart1_3(2,:),'g','LineWidth',2,"Visible","on");
            app.CheckBox_Z3.Value = true;
        end

        % Value changed function: CheckBox_Z3
        function CheckBox_Z3ValueChanged(app, event)
            value = app.CheckBox_Z3.Value;
            if value
                app.Button_stateok_radar3.UserData.plot.Visible = "on";
                app.Button_stateok_radar3.UserData.scatter.Visible = "on";
            else
                app.Button_stateok_radar3.UserData.plot.Visible = "off";
                app.Button_stateok_radar3.UserData.scatter.Visible = "off";
            end
        end

        % Value changed function: CheckBox_Z1
        function CheckBox_Z1ValueChanged(app, event)
            value = app.CheckBox_Z1.Value;
            if value
                app.Button_stateok_radar1.UserData.plot.Visible = "on";
                app.Button_stateok_radar1.UserData.scatter.Visible = "on";
            else
                app.Button_stateok_radar1.UserData.plot.Visible = "off";
                app.Button_stateok_radar1.UserData.scatter.Visible = "off";
            end
        end

        % Value changed function: CheckBox_Z2
        function CheckBox_Z2ValueChanged(app, event)
            value = app.CheckBox_Z2.Value;
            if value
                app.Button_stateok_radar2.UserData.plot.Visible = "on";
                app.Button_stateok_radar2.UserData.scatter.Visible = "on";
            else
                app.Button_stateok_radar2.UserData.plot.Visible = "off";
                app.Button_stateok_radar2.UserData.scatter.Visible = "off";
            end
        end

        % Value changed function: CheckBox_X
        function CheckBox_XValueChanged(app, event)
            value = app.CheckBox_X.Value;
            if value
                app.Button_stateok.UserData.plot.Visible = "on";
            else
                app.Button_stateok.UserData.plot.Visible = "off";
            end
        end

        % Button pushed function: Button_stateok_radar1f
        function Button_stateok_radar1fPushed(app, event)
            if app.KFButton.Value == true
                R1f = [app.EditField_Rpol1f_theta.Value^2 0;0 app.EditField_Rpol1f_rho.Value^2];
                [Xhat1_1,P1_1] = kalmanFilter(app.Button_stateok_radar1.UserData.Zcart1_1,app.EditField_Qf_vx_1.Value,R1f,app.EditField_T.Value,app.Button_stateok_radar1.UserData.origin1);
                app.Button_stateok_radar1f.UserData = struct('Xhat1_1',Xhat1_1,'P1_1',P1_1);
                app.Button_stateok_radar1f.UserData.plot = plot(app.UIAxes1,Xhat1_1(1,:),Xhat1_1(3,:),'r','LineWidth',2);
                app.CheckBox_Xhat1.Value = true;
            elseif app.EKFButton.Value == true
            end
        end

        % Value changed function: CheckBox_Xhat1
        function CheckBox_Xhat1ValueChanged(app, event)
            value = app.CheckBox_Xhat1.Value;
            if value
                app.Button_stateok_radar1f.UserData.plot.Visible = "on";
            else
                app.Button_stateok_radar1f.UserData.plot.Visible = "off";
            end
        end

        % Button pushed function: Button_stateok_radar2f
        function Button_stateok_radar2fPushed(app, event)
            if app.KFButton_2.Value == true
                R2f = [app.EditField_Rpol2f_theta.Value^2 0;0 app.EditField_Rpol2f_rho.Value^2];
                [Xhat1_2,P1_2] = kalmanFilter(app.Button_stateok_radar2.UserData.Zcart1_2,app.EditField_Qf_vx_2.Value,R2f,app.EditField_T.Value,app.Button_stateok_radar2.UserData.origin2);
                app.Button_stateok_radar2f.UserData = struct('Xhat1_2',Xhat1_2,'P1_2',P1_2);
                app.Button_stateok_radar2f.UserData.plot = plot(app.UIAxes1,Xhat1_2(1,:),Xhat1_2(3,:),'b','LineWidth',2);
                app.CheckBox_Xhat2.Value = true;
            elseif app.EKFButton_2.Value == true
            end
        end

        % Value changed function: CheckBox_Xhat2
        function CheckBox_Xhat2ValueChanged(app, event)
            value = app.CheckBox_Xhat2.Value;
            if value
                app.Button_stateok_radar2f.UserData.plot.Visible = "on";
            else
                app.Button_stateok_radar2f.UserData.plot.Visible = "off";
            end
        end

        % Button pushed function: Button_stateok_radar3f
        function Button_stateok_radar3fPushed(app, event)
            if app.KFButton_3.Value == true
                R3f = [app.EditField_Rpol3f_theta.Value^2 0;0 app.EditField_Rpol3f_rho.Value^2];
                [Xhat1_3,P1_3] = kalmanFilter(app.Button_stateok_radar3.UserData.Zcart1_3,app.EditField_Qf_vx_3.Value,R3f,app.EditField_T.Value,app.Button_stateok_radar3.UserData.origin3);
                app.Button_stateok_radar3f.UserData = struct('Xhat1_3',Xhat1_3,'P1_3',P1_3);
                app.Button_stateok_radar3f.UserData.plot = plot(app.UIAxes1,Xhat1_3(1,:),Xhat1_3(3,:),'g','LineWidth',2);
                app.CheckBox_Xhat3.Value = true;
            elseif app.EKFButton_3.Value == true
            end
        end

        % Value changed function: CheckBox_Xhat3
        function CheckBox_Xhat3ValueChanged(app, event)
            value = app.CheckBox_Xhat3.Value;
            if value
                app.Button_stateok_radar3f.UserData.plot.Visible = "on";
            else
                app.Button_stateok_radar3f.UserData.plot.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '误差')
            xlabel(app.UIAxes2, 'T/s')
            ylabel(app.UIAxes2, 'RMSE/m')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '轨迹')
            xlabel(app.UIAxes1, 'X/m')
            ylabel(app.UIAxes1, 'Y/m')
            zlabel(app.UIAxes1, 'Z')
            app.CheckBox_X = uicheckbox(app.UIFigure);
            app.CheckBox_X.ValueChangedFcn = createCallbackFcn(app, @CheckBox_XValueChanged, true);
            app.CheckBox_X.Text = '运动轨迹';
            app.CheckBox_Z1 = uicheckbox(app.UIFigure);
            app.CheckBox_Z1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Z1ValueChanged, true);
            app.CheckBox_Z1.Text = '量测轨迹_雷达1';
            app.CheckBox_Z2 = uicheckbox(app.UIFigure);
            app.CheckBox_Z2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Z2ValueChanged, true);
            app.CheckBox_Z2.Text = '量测轨迹_雷达2';
            app.CheckBox_Z3 = uicheckbox(app.UIFigure);
            app.CheckBox_Z3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Z3ValueChanged, true);
            app.CheckBox_Z3.Text = '量测轨迹_雷达3';
            app.CheckBox_Xhat1 = uicheckbox(app.UIFigure);
            app.CheckBox_Xhat1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Xhat1ValueChanged, true);
            app.CheckBox_Xhat1.Text = '估计轨迹_雷达1';
            app.CheckBox_Xhat2 = uicheckbox(app.UIFigure);
            app.CheckBox_Xhat2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Xhat2ValueChanged, true);
            app.CheckBox_Xhat2.Text = '估计轨迹_雷达2';
            app.CheckBox_Xhat3 = uicheckbox(app.UIFigure);
            app.CheckBox_Xhat3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Xhat3ValueChanged, true);
            app.CheckBox_Xhat3.Text = '估计轨迹_雷达3';
            app.MonteCarloCheckBox = uicheckbox(app.UIFigure);
            app.MonteCarloCheckBox.Text = 'Monte Carlo';
            app.PCRLBCheckBox = uicheckbox(app.UIFigure);
            app.PCRLBCheckBox.Text = 'PCRLB';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '雷达1';
            app.Label_5 = uilabel(app.Tab_1);
            app.Label_5.Text = '雷达坐标';
            app.EditField_origin1_x = uieditfield(app.Tab_1, 'numeric');
            app.EditField_origin1_x.Value = 1500;
            app.xmLabel_2 = uilabel(app.Tab_1);
            app.xmLabel_2.Text = 'x/m';
            app.EditField_origin1_y = uieditfield(app.Tab_1, 'numeric');
            app.EditField_origin1_y.Value = 5500;
            app.ymLabel_2 = uilabel(app.Tab_1);
            app.ymLabel_2.Text = 'y/m';
            app.rhomLabel = uilabel(app.Tab_1);
            app.rhomLabel.Text = 'rho/m';
            app.thetaradianLabel = uilabel(app.Tab_1);
            app.thetaradianLabel.Text = {'theta/'; 'radian'};
            app.EditField_Rpol1_theta = uieditfield(app.Tab_1, 'numeric');
            app.EditField_Rpol1_theta.Value = 0.04;
            app.EditField_Rpol1_rho = uieditfield(app.Tab_1, 'numeric');
            app.EditField_Rpol1_rho.Value = 60;
            app.Label_8 = uilabel(app.Tab_1);
            app.Label_8.Text = {'量测噪声'; '标准差'};
            app.Button_stateok_radar1 = uibutton(app.Tab_1, 'push');
            app.Button_stateok_radar1.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar1Pushed, true);
            app.Button_stateok_radar1.Text = '确认';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '雷达2';
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '雷达坐标';
            app.EditField_origin2_x = uieditfield(app.Tab_2, 'numeric');
            app.EditField_origin2_x.Value = 4000;
            app.xmLabel_3 = uilabel(app.Tab_2);
            app.xmLabel_3.Text = 'x/m';
            app.EditField_origin2_y = uieditfield(app.Tab_2, 'numeric');
            app.EditField_origin2_y.Value = 4000;
            app.ymLabel_3 = uilabel(app.Tab_2);
            app.ymLabel_3.Text = 'y/m';
            app.rhomLabel_2 = uilabel(app.Tab_2);
            app.rhomLabel_2.Text = 'rho/m';
            app.thetaLabel_2 = uilabel(app.Tab_2);
            app.thetaLabel_2.Text = {'theta/'; '弧度'};
            app.EditField_Rpol2_theta = uieditfield(app.Tab_2, 'numeric');
            app.EditField_Rpol2_theta.Value = 0.05;
            app.EditField_Rpol2_rho = uieditfield(app.Tab_2, 'numeric');
            app.EditField_Rpol2_rho.Value = 150;
            app.Label_9 = uilabel(app.Tab_2);
            app.Label_9.Text = {'量测噪声'; '标准差'};
            app.Button_stateok_radar2 = uibutton(app.Tab_2, 'push');
            app.Button_stateok_radar2.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar2Pushed, true);
            app.Button_stateok_radar2.Text = '确认';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '雷达3';
            app.Label_7 = uilabel(app.Tab_3);
            app.Label_7.Text = '雷达坐标';
            app.EditField_origin3_x = uieditfield(app.Tab_3, 'numeric');
            app.EditField_origin3_x.Value = 6000;
            app.xmLabel_4 = uilabel(app.Tab_3);
            app.xmLabel_4.Text = 'x/m';
            app.EditField_origin3_y = uieditfield(app.Tab_3, 'numeric');
            app.EditField_origin3_y.Value = 3000;
            app.ymLabel_4 = uilabel(app.Tab_3);
            app.ymLabel_4.Text = 'y/m';
            app.rhomLabel_3 = uilabel(app.Tab_3);
            app.rhomLabel_3.Text = 'rho/m';
            app.thetaLabel_3 = uilabel(app.Tab_3);
            app.thetaLabel_3.Text = {'theta/'; '弧度'};
            app.EditField_Rpol3_theta = uieditfield(app.Tab_3, 'numeric');
            app.EditField_Rpol3_theta.Value = 0.09;
            app.EditField_Rpol3_rho = uieditfield(app.Tab_3, 'numeric');
            app.EditField_Rpol3_rho.Value = 100;
            app.Label_10 = uilabel(app.Tab_3);
            app.Label_10.Text = {'量测噪声'; '标准差'};
            app.Button_stateok_radar3 = uibutton(app.Tab_3, 'push');
            app.Button_stateok_radar3.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar3Pushed, true);
            app.Button_stateok_radar3.Text = '确认';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '运动轨迹';
            app.SLabel = uilabel(app.Panel);
            app.SLabel.Text = 'S';
            app.xmLabel = uilabel(app.Panel);
            app.xmLabel.Text = 'x/m';
            app.xmsLabel = uilabel(app.Panel);
            app.xmsLabel.Text = 'x/m/s';
            app.ymLabel = uilabel(app.Panel);
            app.ymLabel.Text = 'y/m';
            app.ymsLabel_3 = uilabel(app.Panel);
            app.ymsLabel_3.Text = 'y/m/s';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '初始状态';
            app.xmsLabel_2 = uilabel(app.Panel);
            app.xmsLabel_2.Text = 'x/m/s';
            app.ymsLabel_2 = uilabel(app.Panel);
            app.ymsLabel_2.Text = 'y/m/s';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = {'运动噪声'; '标准差'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = '测量次数';
            app.EditField_k = uieditfield(app.Panel, 'numeric');
            app.EditField_k.Value = 50;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '测量间隔';
            app.EditField_T = uieditfield(app.Panel, 'numeric');
            app.EditField_T.Value = 1;
            app.EditField_X_x = uieditfield(app.Panel, 'numeric');
            app.EditField_X_x.Value = 1000;
            app.EditField_X_y = uieditfield(app.Panel, 'numeric');
            app.EditField_X_y.Value = 8000;
            app.EditField_X_vx = uieditfield(app.Panel, 'numeric');
            app.EditField_X_vx.Value = 170;
            app.EditField_X_vy = uieditfield(app.Panel, 'numeric');
            app.EditField_X_vy.Value = -120;
            app.EditField_Q_vx = uieditfield(app.Panel, 'numeric');
            app.EditField_Q_vx.Value = 5;
            app.EditField_Q_vy = uieditfield(app.Panel, 'numeric');
            app.EditField_Q_vy.Value = 5;
            app.Button_stateok = uibutton(app.Panel, 'push');
            app.Button_stateok.ButtonPushedFcn = createCallbackFcn(app, @Button_stateokPushed, true);
            app.Button_stateok.Text = '确认';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '雷达1滤波';
            app.ymsLabel_6 = uilabel(app.Tab_4);
            app.ymsLabel_6.Text = 'y/m/s';
            app.xmsLabel_5 = uilabel(app.Tab_4);
            app.xmsLabel_5.Text = 'x/m/s';
            app.Label_19 = uilabel(app.Tab_4);
            app.Label_19.Text = {'运动噪声'; '标准差'};
            app.EditField_Qf_vx_1 = uieditfield(app.Tab_4, 'numeric');
            app.EditField_Qf_vx_1.Value = 5;
            app.EditField_Qf_vy_1 = uieditfield(app.Tab_4, 'numeric');
            app.EditField_Qf_vy_1.Value = 5;
            app.rhomLabel_6 = uilabel(app.Tab_4);
            app.rhomLabel_6.Text = 'rho/m';
            app.thetaLabel_6 = uilabel(app.Tab_4);
            app.thetaLabel_6.Text = {'theta/'; '弧度'};
            app.EditField_Rpol1f_rho = uieditfield(app.Tab_4, 'numeric');
            app.EditField_Rpol1f_rho.Value = 60;
            app.Label_20 = uilabel(app.Tab_4);
            app.Label_20.Text = {'量测噪声'; '标准差'};
            app.EditField_Rpol1f_theta = uieditfield(app.Tab_4, 'numeric');
            app.EditField_Rpol1f_theta.Value = 0.04;
            app.Button_stateok_radar1f = uibutton(app.Tab_4, 'push');
            app.Button_stateok_radar1f.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar1fPushed, true);
            app.Button_stateok_radar1f.Text = '确认';
            app.ButtonGroup = uibuttongroup(app.Tab_4);
            app.ButtonGroup.Title = '滤波方式';
            app.KFButton = uiradiobutton(app.ButtonGroup);
            app.KFButton.Text = 'KF滤波';
            app.KFButton.Value = true;
            app.EKFButton = uiradiobutton(app.ButtonGroup);
            app.EKFButton.Text = 'EKF滤波';
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '雷达2滤波';
            app.ymsLabel_5 = uilabel(app.Tab_5);
            app.ymsLabel_5.Text = 'y/m/s';
            app.xmsLabel_4 = uilabel(app.Tab_5);
            app.xmsLabel_4.Text = 'x/m/s';
            app.Label_17 = uilabel(app.Tab_5);
            app.Label_17.Text = {'运动噪声'; '标准差'};
            app.EditField_Qf_vx_2 = uieditfield(app.Tab_5, 'numeric');
            app.EditField_Qf_vx_2.Value = 5;
            app.EditField_Qf_vy_2 = uieditfield(app.Tab_5, 'numeric');
            app.EditField_Qf_vy_2.Value = 5;
            app.rhomLabel_5 = uilabel(app.Tab_5);
            app.rhomLabel_5.Text = 'rho/m';
            app.thetaLabel_5 = uilabel(app.Tab_5);
            app.thetaLabel_5.Text = {'theta/'; '弧度'};
            app.EditField_Rpol2f_rho = uieditfield(app.Tab_5, 'numeric');
            app.EditField_Rpol2f_rho.Value = 100;
            app.Label_18 = uilabel(app.Tab_5);
            app.Label_18.Text = {'量测噪声'; '标准差'};
            app.EditField_Rpol2f_theta = uieditfield(app.Tab_5, 'numeric');
            app.EditField_Rpol2f_theta.Value = 0.05;
            app.Button_stateok_radar2f = uibutton(app.Tab_5, 'push');
            app.Button_stateok_radar2f.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar2fPushed, true);
            app.Button_stateok_radar2f.Text = '确认';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_5);
            app.ButtonGroup_2.Title = '滤波方式';
            app.KFButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.KFButton_2.Text = 'KF滤波';
            app.KFButton_2.Value = true;
            app.EKFButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.EKFButton_2.Text = 'EKF滤波';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '雷达3滤波';
            app.ymsLabel_4 = uilabel(app.Tab_6);
            app.ymsLabel_4.Text = 'y/m/s';
            app.xmsLabel_3 = uilabel(app.Tab_6);
            app.xmsLabel_3.Text = 'x/m/s';
            app.Label_15 = uilabel(app.Tab_6);
            app.Label_15.Text = {'运动噪声'; '标准差'};
            app.EditField_Qf_vx_3 = uieditfield(app.Tab_6, 'numeric');
            app.EditField_Qf_vx_3.Value = 5;
            app.EditField_Qf_vy_3 = uieditfield(app.Tab_6, 'numeric');
            app.EditField_Qf_vy_3.Value = 5;
            app.rhomLabel_4 = uilabel(app.Tab_6);
            app.rhomLabel_4.Text = 'rho/m';
            app.thetaLabel_4 = uilabel(app.Tab_6);
            app.thetaLabel_4.Text = {'theta/'; '弧度'};
            app.EditField_Rpol3f_rho = uieditfield(app.Tab_6, 'numeric');
            app.EditField_Rpol3f_rho.Value = 150;
            app.Label_16 = uilabel(app.Tab_6);
            app.Label_16.Text = {'量测噪声'; '标准差'};
            app.EditField_Rpol3f_theta = uieditfield(app.Tab_6, 'numeric');
            app.EditField_Rpol3f_theta.Value = 0.09;
            app.Button_stateok_radar3f = uibutton(app.Tab_6, 'push');
            app.Button_stateok_radar3f.ButtonPushedFcn = createCallbackFcn(app, @Button_stateok_radar3fPushed, true);
            app.Button_stateok_radar3f.Text = '确认';
            app.ButtonGroup_3 = uibuttongroup(app.Tab_6);
            app.ButtonGroup_3.Title = '滤波方式';
            app.KFButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.KFButton_3.Text = 'KF滤波';
            app.KFButton_3.Value = true;
            app.EKFButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.EKFButton_3.Text = 'EKF滤波';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '数据融合';
            app.CheckBox_CI = uicheckbox(app.Panel_2);
            app.CheckBox_CI.Text = '融合后轨迹';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '融合系数';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '雷达1';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField.Value = 1;
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '雷达2';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2.Value = 1;
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '雷达3';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarTrack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end